classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        PhaseSliderLabel     matlab.ui.control.Label
        tWeightSlider        matlab.ui.control.Slider
        WeightSliderLabel    matlab.ui.control.Label
        mWeightSlider        matlab.ui.control.Slider
        mWeightLabel         matlab.ui.control.Label
        tWeightLabel         matlab.ui.control.Label
        ImportxlsfileButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Pax % Polar axes
        Data % Data       
        Box % Description
    end
    
    methods (Access = private)
        
        function updateplot(app)
            global gcolor; setpath;
            mWeight = app.mWeightSlider.Value;
            tWeight = app.tWeightSlider.Value;
            zWeight = getcomplex(mWeight, tWeight);
            D = app.Data;
            modes = unique(D.modes);
            app.Pax.NextPlot = 'replace';
            for i = 1:length(modes)
                g = colormod(i);
                a = v_isakovich(D(i,:), zWeight);
                zP2P = getcomplex(D.mP2P(i), D.tP2P(i));
                points = arrowapp(zP2P, a);
                z = [linspace(zP2P, a, 10e3), points];
                polarplot(app.Pax, z, '.', ...
                    'LineWidth', 5, ...
                    'Color',  gcolor(g,:));
                app.Pax.NextPlot = 'add';
            end
            legend(app.Pax, cellstr(modes));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, filepath)
            % Create polar axes and position it in pixels
            app.Pax = polaraxes(app.UIFigure);
            app.Pax.Units = 'pixels';
            app.Pax.Position = [220 40 400 400];
            %app.Data = readtable(fullfile('results\summary.xls'));
        end

        % Value changed function: mWeightSlider, tWeightSlider
        function SliderChange(app, event)
            app.mWeightLabel.Text = num2str(app.mWeightSlider.Value,'%.1f');
            app.tWeightLabel.Text = num2str(app.tWeightSlider.Value,'%.0f');   
            if app.Box == 1
                updateplot(app);
            end
            %updateplot(app);
        end

        % Button pushed function: ImportxlsfileButton
        function ImportxlsfileButtonPushed(app, event)
            [file, path] = uigetfile('*.xls');
            if ~isequal(file,0)
                app.Data = readtable(fullfile(path, file));
                app.mWeightSlider.Limits = [0 ceil(2*app.Data.mWeightTarget(1))];
                app.mWeightSlider.Value = app.Data.mWeightTarget(1);
                app.mWeightLabel.Text = num2str(app.mWeightSlider.Value,'%.1f');
                app.tWeightSlider.Value = wrapTo360(app.Data.tWeightTarget(1));
                app.tWeightLabel.Text = num2str(app.tWeightSlider.Value,'%.0f');
                app.Box = 1;
                updateplot(app);
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Polar Plot';
            app.PhaseSliderLabel = uilabel(app.UIFigure);
            app.PhaseSliderLabel.Text = 'Phase';
            app.tWeightSlider = uislider(app.UIFigure);
            app.tWeightSlider.Limits = [0 360];
            app.tWeightSlider.ValueChangedFcn = createCallbackFcn(app, @SliderChange, true);
            app.WeightSliderLabel = uilabel(app.UIFigure);
            app.WeightSliderLabel.Text = 'Weight';
            app.mWeightSlider = uislider(app.UIFigure);
            app.mWeightSlider.Limits = [0 200];
            app.mWeightSlider.ValueChangedFcn = createCallbackFcn(app, @SliderChange, true);
            app.mWeightLabel = uilabel(app.UIFigure);
            app.mWeightLabel.Text = 'NaN';
            app.tWeightLabel = uilabel(app.UIFigure);
            app.tWeightLabel.Text = 'NaN';
            app.ImportxlsfileButton = uibutton(app.UIFigure, 'push');
            app.ImportxlsfileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportxlsfileButtonPushed, true);
            app.ImportxlsfileButton.Text = 'Import xls-file';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end