classdef app_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        PlotTab                     matlab.ui.container.Tab
        ScalemaximumEditFieldLabel  matlab.ui.control.Label
        ScalemaximumEditField       matlab.ui.control.NumericEditField
        ScaleminimumEditFieldLabel  matlab.ui.control.Label
        ScaleminimumEditField       matlab.ui.control.NumericEditField
        AutoscaleSwitchLabel        matlab.ui.control.Label
        AutoscaleSwitch             matlab.ui.control.Switch
        PhaseSliderLabel            matlab.ui.control.Label
        tWeightSlider               matlab.ui.control.Slider
        WeightSliderLabel           matlab.ui.control.Label
        mWeightSlider               matlab.ui.control.Slider
        SwitchRef                   matlab.ui.control.Switch
        ImportRawXlsFileButton      matlab.ui.control.Button
        mWeightLabel                matlab.ui.control.Label
        tWeightLabel                matlab.ui.control.Label
        Table                       matlab.ui.container.Tab
    end

    
    properties (Access = private)
        Pax % Polar axes
        Data % Data       
        Box % Description
        Type % All or reference
    end
    
    methods (Access = private)
        
        function updateplot(app)
            global gcolor; setpath;
            mWeight = app.mWeightSlider.Value;
            tWeight = app.tWeightSlider.Value;
            zWeight = getcomplex(mWeight, tWeight);
            D = app.Data;
            modes = unique(D.modes);
            app.Pax.NextPlot = 'replace';
            for i = 1:length(modes)
                g = colormod(i);
                a = v_isakovich(D(i,:), zWeight);
                zP2P = getcomplex(D.mP2P(i), D.tP2P(i));
                points = arrowapp(zP2P, a);
                z = [linspace(zP2P, a, 10e3), points];
                polarplot(app.Pax, z, '.', ...
                    'LineWidth', 5, ...
                    'Color',  gcolor(g,:));
                app.Pax.NextPlot = 'add';
            end
            legend(app.Pax,cellstr(modes), ...
                'Location','northeastoutside');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, filepath)
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.8;
            height = screenHeight*0.8;
            drawnow;
            app.UIFigure.Position = [left bottom width height];   
            % Create polar axes and position it in pixels
            app.Pax = polaraxes(app.PlotTab);
            app.Pax.Units = 'pixels';
            app.Pax.Position = [220 40 width-220 height-120];
            app.Type = 'all';
        end

        % Value changed function: mWeightSlider, tWeightSlider
        function SliderChange(app, event)
            app.mWeightLabel.Text = num2str(app.mWeightSlider.Value,'%.1f');
            app.tWeightLabel.Text = num2str(app.tWeightSlider.Value,'%.0f');   
            if app.Box == 1
                updateplot(app);
            end
        end

        % Callback function
        function ImportxlsfileButtonPushed(app, event)
            [file, path] = uigetfile('*.xls');
            if ~isequal(file,0)
                app.Data = readtable(fullfile(path, file));
                app.mWeightSlider.Limits = [0 ceil(2*app.Data.mWeightTarget(1))];
                app.mWeightSlider.Value = app.Data.mWeightTarget(1);
                app.mWeightLabel.Text = num2str(app.mWeightSlider.Value,'%.1f');
                app.tWeightSlider.Value = wrapTo360(app.Data.tWeightTarget(1));
                app.tWeightLabel.Text = num2str(app.tWeightSlider.Value,'%.0f');
                app.Box = 1;
                updateplot(app);
            end            
        end

        % Callback function
        function AutoscaleCheckBoxValueChanged(app, event)
            value = app.AutoscaleCheckBox.Value;
            if ~value
                rl = get(app.Pax, 'RLim');
                app.ScalemaximumEditField.Visible = 1;
                app.ScalemaximumEditFieldLabel.Visible = 1;
                app.ScaleminimumEditField.Visible = 1;
                app.ScaleminimumEditFieldLabel.Visible = 1;
                pos = app.ScalemaximumEditField.Position;
                app.ScalemaximumEditField.Position = [pos(1),pos(2),50,25];
                pos = app.ScaleminimumEditField.Position;
                app.ScaleminimumEditField.Position = [pos(1),pos(2),50,25];
                app.ScalemaximumEditField.Value = rl(2);
                app.ScaleminimumEditField.Value = rl(1);
            else
                app.ScalemaximumEditField.Visible = 0;
                app.ScalemaximumEditFieldLabel.Visible = 0;
                app.ScaleminimumEditField.Visible = 0;
                app.ScaleminimumEditFieldLabel.Visible = 0;
                app.Pax.RLimMode = 'auto';
            end
        end

        % Value changed function: ScalemaximumEditField
        function ScalemaximumEditFieldValueChanged(app, event)
            maximum = app.ScalemaximumEditField.Value;
            minimum = app.ScaleminimumEditField.Value;
            if maximum > minimum
                rlim(app.Pax, [minimum maximum]);
            end
        end

        % Value changed function: ScaleminimumEditField
        function ScaleminimumEditFieldValueChanged(app, event)
            maximum = app.ScalemaximumEditField.Value;
            minimum = app.ScaleminimumEditField.Value;
            if maximum > minimum
                rlim(app.Pax, [minimum maximum]);            
            end
        end

        % Button pushed function: ImportRawXlsFileButton
        function ImportRawXlsFileButtonPushed(app, event)
            [file, path] = uigetfile({'*.xls'});
            if ~isequal(file,0)
                T = imxls(fullfile(path, file));
                T = pullcorrectweight(T, app.Type);
                app.Data = sumsen(T);
                app.mWeightSlider.Limits = [0 ceil(2*app.Data.mWeightTarget(1))];
                app.mWeightSlider.Value = app.Data.mWeightTarget(1);
                app.mWeightLabel.Text = num2str(app.mWeightSlider.Value,'%.1f');
                app.tWeightSlider.Value = wrapTo360(app.Data.tWeightTarget(1));
                app.tWeightLabel.Text = num2str(app.tWeightSlider.Value,'%.0f');
                app.Box = 1;
                updateplot(app);     
                writetable(app.Data, fullfile('summary.xls'))
            end  
        end

        % Value changed function: AutoscaleSwitch
        function AutoscaleSwitchValueChanged(app, event)
            value = app.AutoscaleSwitch.Value;
            if value ~= "On"
                rl = get(app.Pax, 'RLim');
                app.ScalemaximumEditField.Visible = 1;
                app.ScalemaximumEditFieldLabel.Visible = 1;
                app.ScaleminimumEditField.Visible = 1;
                app.ScaleminimumEditFieldLabel.Visible = 1;
                pos = app.ScalemaximumEditField.Position;
                app.ScalemaximumEditField.Position = [pos(1),pos(2),50,25];
                pos = app.ScaleminimumEditField.Position;
                app.ScaleminimumEditField.Position = [pos(1),pos(2),50,25];
                app.ScalemaximumEditField.Value = rl(2);
                app.ScaleminimumEditField.Value = rl(1);
            else
                app.ScalemaximumEditField.Visible = 0;
                app.ScalemaximumEditFieldLabel.Visible = 0;
                app.ScaleminimumEditField.Visible = 0;
                app.ScaleminimumEditFieldLabel.Visible = 0;
                app.Pax.RLimMode = 'auto';
            end
        end

        % Value changed function: SwitchRef
        function SwitchRefValueChanged(app, event)
            app.SwitchRef.Items = {'ref','all'};
            app.Type = app.SwitchRef.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Polar Plot';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.ScalemaximumEditFieldLabel = uilabel(app.PlotTab);
            app.ScalemaximumEditFieldLabel.Visible = 'off';
            app.ScalemaximumEditFieldLabel.Text = 'Scale maximum';
            app.ScalemaximumEditField = uieditfield(app.PlotTab, 'numeric');
            app.ScalemaximumEditField.ValueChangedFcn = createCallbackFcn(app, @ScalemaximumEditFieldValueChanged, true);
            app.ScalemaximumEditField.Visible = 'off';
            app.ScaleminimumEditFieldLabel = uilabel(app.PlotTab);
            app.ScaleminimumEditFieldLabel.Visible = 'off';
            app.ScaleminimumEditFieldLabel.Text = 'Scale minimum';
            app.ScaleminimumEditField = uieditfield(app.PlotTab, 'numeric');
            app.ScaleminimumEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleminimumEditFieldValueChanged, true);
            app.ScaleminimumEditField.Visible = 'off';
            app.AutoscaleSwitchLabel = uilabel(app.PlotTab);
            app.AutoscaleSwitchLabel.Text = 'Autoscale';
            app.AutoscaleSwitch = uiswitch(app.PlotTab, 'slider');
            app.AutoscaleSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoscaleSwitchValueChanged, true);
            app.AutoscaleSwitch.Value = 'On';
            app.PhaseSliderLabel = uilabel(app.PlotTab);
            app.PhaseSliderLabel.Text = 'Phase';
            app.tWeightSlider = uislider(app.PlotTab);
            app.tWeightSlider.Limits = [0 360];
            app.tWeightSlider.ValueChangedFcn = createCallbackFcn(app, @SliderChange, true);
            app.WeightSliderLabel = uilabel(app.PlotTab);
            app.WeightSliderLabel.Text = 'Weight';
            app.mWeightSlider = uislider(app.PlotTab);
            app.mWeightSlider.Limits = [0 200];
            app.mWeightSlider.ValueChangedFcn = createCallbackFcn(app, @SliderChange, true);
            app.SwitchRef = uiswitch(app.PlotTab, 'slider');
            app.SwitchRef.Items = {'ref', 'all'};
            app.SwitchRef.ValueChangedFcn = createCallbackFcn(app, @SwitchRefValueChanged, true);
            app.SwitchRef.Value = 'all';
            app.ImportRawXlsFileButton = uibutton(app.PlotTab, 'push');
            app.ImportRawXlsFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportRawXlsFileButtonPushed, true);
            app.ImportRawXlsFileButton.Text = 'Import raw xls-file';
            app.mWeightLabel = uilabel(app.PlotTab);
            app.mWeightLabel.Text = 'NaN';
            app.tWeightLabel = uilabel(app.PlotTab);
            app.tWeightLabel.Text = 'NaN';
            app.Table = uitab(app.TabGroup);
            app.Table.Title = 'Table';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_v1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end